classdef SwipeBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SwipeBotAppUIFigure       matlab.ui.Figure
        EnvironmentSwitch         matlab.ui.control.ToggleSwitch
        HandSwitch                matlab.ui.control.ToggleSwitch
        LoadSwipeBotSystemButton  matlab.ui.control.Button
        CleanWindowButton         matlab.ui.control.Button
        Title                     matlab.ui.control.Label
        Subtitle                  matlab.ui.control.Label
        SafeCoLogo                matlab.ui.control.Image
        TextArea                  matlab.ui.control.TextArea
        MovetoHomeButton          matlab.ui.control.Button
        MovetoStoreButton         matlab.ui.control.Button
        StatusMessagesLabel       matlab.ui.control.Label
        HandSwitchLabel           matlab.ui.control.Label
        EMERGENCYSTOPButton       matlab.ui.control.StateButton
        TeachButton               matlab.ui.control.Button
        EnvironmentSwitchLabel    matlab.ui.control.Label
        BenjaminLebherzBjarneUngemachLachlanWrightLabel  matlab.ui.control.Label
        clearButton               matlab.ui.control.Button
        RunIBVSButton             matlab.ui.control.StateButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadSwipeBotSystemButton
        function LoadSwipeBotSystemButtonPushed(app, event)
            app.HandSwitch.Value = "Off";
            app.EnvironmentSwitch.Value = "On";
            
            clf                        % clear all windows
            clear all
            clc
            swipebot = SwipeBot;   % generate the SwipeBot
            assignin('base', "swipebot", swipebot);
        end

        % Button pushed function: CleanWindowButton
        function CleanWindowButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.CleanWindow(app);  % start Window-Cleaning-Program
        end

        % Button pushed function: MovetoHomeButton
        function MovetoHomeButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.MoveTo("home",app);  % move robots to home position
        end

        % Button pushed function: MovetoStoreButton
        function MovetoStoreButtonPushed(app, event)
            swipebot = evalin("base","swipebot");
            swipebot.MoveTo("store",app);  % move robots to store position
        end

        % Value changed function: HandSwitch
        function HandSwitchValueChanged(app, event)
            value = app.HandSwitch.Value;
            swipebot = evalin("base","swipebot");
            if value == "On"
                swipebot.PlotAndColourHand;
            end
            if value == "Off"
                delete(findobj('Tag',swipebot.hand.name));
            end
        end

        % Value changed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonValueChanged(app, event)
            value = app.EMERGENCYSTOPButton.Value;
        end

        % Button pushed function: TeachButton
        function TeachButtonPushed(app, event)
            SwipeBotTeach;
        end

        % Value changed function: EnvironmentSwitch
        function EnvironmentSwitchValueChanged(app, event)
            value = app.EnvironmentSwitch.Value;
            swipebot = evalin("base","swipebot");
            if value == "On"
                swipebot.environment = PlaceObject("Environment.ply", [0,0,-0.2]);
                swipebot.floor = surf([-4,-4;4.3,4.3],[-1.6,3.6;-1.6,3.6],[-0.2,-0.2;-0.2,-0.2],...
                         'CData',imread('Grass.jpg'),'FaceColor','texturemap');
            end
            if value == "Off"
                delete(swipebot.environment);
                delete(swipebot.floor);
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.TextArea.Value = " ";
        end

        % Value changed function: RunIBVSButton
        function RunIBVSButtonValueChanged(app, event)
            value = app.RunIBVSButton.Value;
            swipebot = evalin("base","swipebot");
            if value == 1
                swipebot.RunIBVS(app);
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SwipeBotAppUIFigure = uifigure('Visible', 'off');
            app.SwipeBotAppUIFigure.Name = 'SwipeBotApp';
            app.EnvironmentSwitch = uiswitch(app.SwipeBotAppUIFigure, 'toggle');
            app.EnvironmentSwitch.ValueChangedFcn = createCallbackFcn(app, @EnvironmentSwitchValueChanged, true);
            app.EnvironmentSwitch.Value = 'On';
            app.HandSwitch = uiswitch(app.SwipeBotAppUIFigure, 'toggle');
            app.HandSwitch.ValueChangedFcn = createCallbackFcn(app, @HandSwitchValueChanged, true);
            app.LoadSwipeBotSystemButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.LoadSwipeBotSystemButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSwipeBotSystemButtonPushed, true);
            app.LoadSwipeBotSystemButton.Text = 'Load SwipeBot-System';
            app.CleanWindowButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.CleanWindowButton.ButtonPushedFcn = createCallbackFcn(app, @CleanWindowButtonPushed, true);
            app.CleanWindowButton.Text = 'Clean Window';
            app.Title = uilabel(app.SwipeBotAppUIFigure);
            app.Title.Text = 'SwipeBot';
            app.Subtitle = uilabel(app.SwipeBotAppUIFigure);
            app.Subtitle.Text = 'See the future clearer';
            app.SafeCoLogo = uiimage(app.SwipeBotAppUIFigure);
            app.SafeCoLogo.ImageSource = 'SafeCo Logo.png';
            app.TextArea = uitextarea(app.SwipeBotAppUIFigure);
            app.MovetoHomeButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.MovetoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoHomeButtonPushed, true);
            app.MovetoHomeButton.Text = {'Move to '; 'Home'};
            app.MovetoStoreButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.MovetoStoreButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoStoreButtonPushed, true);
            app.MovetoStoreButton.Text = {'Move to'; 'Store'};
            app.StatusMessagesLabel = uilabel(app.SwipeBotAppUIFigure);
            app.StatusMessagesLabel.Text = 'Status Messages:';
            app.HandSwitchLabel = uilabel(app.SwipeBotAppUIFigure);
            app.HandSwitchLabel.Text = 'Hand';
            app.EMERGENCYSTOPButton = uibutton(app.SwipeBotAppUIFigure, 'state');
            app.EMERGENCYSTOPButton.ValueChangedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonValueChanged, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.TeachButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.TeachButton.ButtonPushedFcn = createCallbackFcn(app, @TeachButtonPushed, true);
            app.TeachButton.Text = 'Teach';
            app.EnvironmentSwitchLabel = uilabel(app.SwipeBotAppUIFigure);
            app.EnvironmentSwitchLabel.Text = 'Environment';
            app.BenjaminLebherzBjarneUngemachLachlanWrightLabel = uilabel(app.SwipeBotAppUIFigure);
            app.BenjaminLebherzBjarneUngemachLachlanWrightLabel.Text = 'Benjamin Lebherz, Bjarne Ungemach, Lachlan Wright';
            app.clearButton = uibutton(app.SwipeBotAppUIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.RunIBVSButton = uibutton(app.SwipeBotAppUIFigure, 'state');
            app.RunIBVSButton.ValueChangedFcn = createCallbackFcn(app, @RunIBVSButtonValueChanged, true);
            app.RunIBVSButton.Text = 'Run IBVS';
            % Show the figure after all components are created
            app.SwipeBotAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwipeBotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SwipeBotAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SwipeBotAppUIFigure)
        end
    end
end