classdef SwipeBotTeach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SafeCoLogo            matlab.ui.control.Image
        Subtitle              matlab.ui.control.Label
        Title                 matlab.ui.control.Label
        UR3Panel              matlab.ui.container.Panel
        Arrows                matlab.ui.control.Image
        Joint1SpinnerLabel    matlab.ui.control.Label
        Joint1Spinner         matlab.ui.control.Spinner
        Joint2SpinnerLabel    matlab.ui.control.Label
        Joint2Spinner         matlab.ui.control.Spinner
        Joint3SpinnerLabel    matlab.ui.control.Label
        Joint3Spinner         matlab.ui.control.Spinner
        Joint5SpinnerLabel    matlab.ui.control.Label
        Joint5Spinner         matlab.ui.control.Spinner
        Joint4SpinnerLabel    matlab.ui.control.Label
        Joint4Spinner         matlab.ui.control.Spinner
        Joint6SpinnerLabel    matlab.ui.control.Label
        Joint6Spinner         matlab.ui.control.Spinner
        XEditFieldLabel       matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel       matlab.ui.control.Label
        ZEditField            matlab.ui.control.NumericEditField
        REditFieldLabel       matlab.ui.control.Label
        REditField            matlab.ui.control.NumericEditField
        PEditFieldLabel       matlab.ui.control.Label
        PEditField            matlab.ui.control.NumericEditField
        YEditField_2Label     matlab.ui.control.Label
        YawEditField          matlab.ui.control.NumericEditField
        ZPButton              matlab.ui.control.Button
        ZMButton              matlab.ui.control.Button
        YPButton              matlab.ui.control.Button
        YMButton              matlab.ui.control.Button
        XPButton              matlab.ui.control.Button
        XMButton              matlab.ui.control.Button
        Turns                 matlab.ui.control.Image
        RMButton              matlab.ui.control.Button
        RPButton              matlab.ui.control.Button
        PMButton              matlab.ui.control.Button
        PPButton              matlab.ui.control.Button
        YawPButton            matlab.ui.control.Button
        YawMButton            matlab.ui.control.Button
        Meca500Panel          matlab.ui.container.Panel
        Arrows2               matlab.ui.control.Image
        ZPButton2             matlab.ui.control.Button
        ZMButton2             matlab.ui.control.Button
        YPButton2             matlab.ui.control.Button
        YMButton2             matlab.ui.control.Button
        XPButton2             matlab.ui.control.Button
        XMButton2             matlab.ui.control.Button
        Turns2                matlab.ui.control.Image
        RMButton2             matlab.ui.control.Button
        RPButton2             matlab.ui.control.Button
        PMButton2             matlab.ui.control.Button
        PPButton2             matlab.ui.control.Button
        YawPButton2           matlab.ui.control.Button
        YawMButton2           matlab.ui.control.Button
        Joint1Spinner_2Label  matlab.ui.control.Label
        Joint1Spinner2        matlab.ui.control.Spinner
        Joint2Spinner_2Label  matlab.ui.control.Label
        Joint2Spinner2        matlab.ui.control.Spinner
        Joint3Spinner_2Label  matlab.ui.control.Label
        Joint3Spinner2        matlab.ui.control.Spinner
        Joint5Spinner_2Label  matlab.ui.control.Label
        Joint5Spinner2        matlab.ui.control.Spinner
        Joint4Spinner_2Label  matlab.ui.control.Label
        Joint4Spinner2        matlab.ui.control.Spinner
        Joint6Spinner_2Label  matlab.ui.control.Label
        Joint6Spinner2        matlab.ui.control.Spinner
        XEditField_2Label     matlab.ui.control.Label
        XEditField2           matlab.ui.control.NumericEditField
        ZEditField_2Label     matlab.ui.control.Label
        ZEditField2           matlab.ui.control.NumericEditField
        REditField_2Label     matlab.ui.control.Label
        REditField2           matlab.ui.control.NumericEditField
        PEditField_2Label     matlab.ui.control.Label
        PEditField2           matlab.ui.control.NumericEditField
        YEditField_3Label     matlab.ui.control.Label
        YEditField2           matlab.ui.control.NumericEditField
        YEditField_4Label     matlab.ui.control.Label
        YawEditField2         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        swipebot
    end
    
    methods (Access = private)       
        % animate UR3
        function animateUR3(app)
            app.swipebot.ur3.animate(deg2rad([app.Joint1Spinner.Value,...
                                              app.Joint2Spinner.Value,...
                                              app.Joint3Spinner.Value,...
                                              app.Joint4Spinner.Value,...
                                              app.Joint5Spinner.Value,...
                                              app.Joint6Spinner.Value]));
            app.swipebot.UpdateGripper3F;
            
            tr = app.swipebot.ur3.fkine(deg2rad([app.Joint1Spinner.Value,...
                                                 app.Joint2Spinner.Value,...
                                                 app.Joint3Spinner.Value,...
                                                 app.Joint4Spinner.Value,...
                                                 app.Joint5Spinner.Value,...
                                                 app.Joint6Spinner.Value]));
            rpy = tr2rpy(tr(1:3,1:3), 'deg');
            
            app.XEditField.Value = tr(1,4);
            app.YEditField.Value = tr(2,4);
            app.ZEditField.Value = tr(3,4);
            app.REditField.Value = rpy(1);
            app.PEditField.Value = rpy(2);
            app.YawEditField.Value = rpy(3);
        end
        
        % animate Meca500
        function animateMeca500(app)
            app.swipebot.meca500.animate(deg2rad([app.Joint1Spinner2.Value,...
                                                  app.Joint2Spinner2.Value,...
                                                  app.Joint3Spinner2.Value,...
                                                  app.Joint4Spinner2.Value,...
                                                  app.Joint5Spinner2.Value,...
                                                  app.Joint6Spinner2.Value]));
            app.swipebot.UpdateGripper2F;
            
            tr2 = app.swipebot.meca500.fkine(deg2rad([app.Joint1Spinner2.Value,...
                                                      app.Joint2Spinner2.Value,...
                                                      app.Joint3Spinner2.Value,...
                                                      app.Joint4Spinner2.Value,...
                                                      app.Joint5Spinner2.Value,...
                                                      app.Joint6Spinner2.Value]));
            rpy2 = tr2rpy(tr2(1:3,1:3), 'deg');
            
            app.XEditField2.Value = tr2(1,4);
            app.YEditField2.Value = tr2(2,4);
            app.ZEditField2.Value = tr2(3,4);
            app.REditField2.Value = rpy2(1);
            app.PEditField2.Value = rpy2(2);
            app.YawEditField2.Value = rpy2(3);
        end
        
        % TeachRMRCUR3
        function teachUR3(app, vel)
            step = 0.01;
            THRESHOLD = 0.001;
            MAX_DAMPING = 0.001;
            
            q = app.swipebot.ur3.getpos;
            J = app.swipebot.ur3.jacob0(q);
            
            manipulability = sqrt(det(J*J'));
            if manipulability < THRESHOLD
                dampingFactor = (1 - (manipulability/THRESHOLD)^2) * MAX_DAMPING;
                qdot = ((J'*J + dampingFactor*eye(6))\J')*vel;
                disp("DLS used");
            else
                qdot = J\vel;
            end
         
            qNew = rad2deg(q + step*qdot');
            
            app.Joint1Spinner.Value = qNew(1);
            app.Joint2Spinner.Value = qNew(2);
            app.Joint3Spinner.Value = qNew(3);
            app.Joint4Spinner.Value = qNew(4);
            app.Joint5Spinner.Value = qNew(5);
            app.Joint6Spinner.Value = qNew(6);
            
            app.animateUR3;
        end
        
        %TeachRMRCMeca500
        function teachMeca500(app, vel2)
            step2 = 0.01;
            THRESHOLD = 0.001;
            MAX_DAMPING = 0.001;
            
            q2 = app.swipebot.meca500.getpos;
            J2 = app.swipebot.meca500.jacob0(q2);
            
            manipulability = sqrt(det(J2*J2'));
            if manipulability < THRESHOLD
                dampingFactor = (1 - (manipulability/THRESHOLD)^2) * MAX_DAMPING;
                qdot2 = ((J2'*J2 + dampingFactor*eye(6))\J2')*vel2;
                disp("DLS used");
            else
                qdot2 = J2\vel2;
            end
         
            qNew2 = rad2deg(q2 + step2*qdot2');
            
            app.Joint1Spinner2.Value = qNew2(1);
            app.Joint2Spinner2.Value = qNew2(2);
            app.Joint3Spinner2.Value = qNew2(3);
            app.Joint4Spinner2.Value = qNew2(4);
            app.Joint5Spinner2.Value = qNew2(5);
            app.Joint6Spinner2.Value = qNew2(6);
            
            app.animateMeca500;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.swipebot = evalin("base", "swipebot");
            % UR3
            q = rad2deg(app.swipebot.ur3.getpos);
            app.Joint1Spinner.Value = q(1);
            app.Joint2Spinner.Value = q(2);
            app.Joint3Spinner.Value = q(3);
            app.Joint4Spinner.Value = q(4);
            app.Joint5Spinner.Value = q(5);
            app.Joint6Spinner.Value = q(6);
            
            tr = app.swipebot.ur3.fkine(deg2rad([app.Joint1Spinner.Value,...
                                                 app.Joint2Spinner.Value,...
                                                 app.Joint3Spinner.Value,...
                                                 app.Joint4Spinner.Value,...
                                                 app.Joint5Spinner.Value,...
                                                 app.Joint6Spinner.Value]));
            rpy = tr2rpy(tr(1:3,1:3), 'deg');
            
            app.XEditField.Value = tr(1,4);
            app.YEditField.Value = tr(2,4);
            app.ZEditField.Value = tr(3,4);
            app.REditField.Value = rpy(1);
            app.PEditField.Value = rpy(2);
            app.YawEditField.Value = rpy(3);
            
            % Meca500
            q2 = rad2deg(app.swipebot.meca500.getpos);
            app.Joint1Spinner2.Value = q2(1);
            app.Joint2Spinner2.Value = q2(2);
            app.Joint3Spinner2.Value = q2(3);
            app.Joint4Spinner2.Value = q2(4);
            app.Joint5Spinner2.Value = q2(5);
            app.Joint6Spinner2.Value = q2(6);
            
            tr2 = app.swipebot.ur3.fkine(deg2rad([app.Joint1Spinner2.Value,...
                                                  app.Joint2Spinner2.Value,...
                                                  app.Joint3Spinner2.Value,...
                                                  app.Joint4Spinner2.Value,...
                                                  app.Joint5Spinner2.Value,...
                                                  app.Joint6Spinner2.Value]));
            rpy2 = tr2rpy(tr2(1:3,1:3), 'deg');
            
            app.XEditField2.Value = tr2(1,4);
            app.YEditField2.Value = tr2(2,4);
            app.ZEditField2.Value = tr2(3,4);
            app.REditField2.Value = rpy2(1);
            app.PEditField2.Value = rpy2(2);
            app.YawEditField2.Value = rpy2(3);
        end

        % Value changing function: Joint1Spinner
        function Joint1SpinnerValueChanging(app, event)
            app.Joint1Spinner.Value = event.Value;
            app.animateUR3;
        end

        % Value changing function: Joint2Spinner
        function Joint2SpinnerValueChanging(app, event)
            app.Joint2Spinner.Value = event.Value;
            app.animateUR3;
        end

        % Value changing function: Joint3Spinner
        function Joint3SpinnerValueChanging(app, event)
            app.Joint3Spinner.Value = event.Value;
            app.animateUR3;
        end

        % Value changing function: Joint4Spinner
        function Joint4SpinnerValueChanging(app, event)
            app.Joint4Spinner.Value = event.Value;
            app.animateUR3;
        end

        % Value changing function: Joint5Spinner
        function Joint5SpinnerValueChanging(app, event)
            app.Joint5Spinner.Value = event.Value;
            app.animateUR3;
        end

        % Value changing function: Joint6Spinner
        function Joint6SpinnerValueChanging(app, event)
            app.Joint6Spinner.Value = event.Value;
            app.animateUR3;
        end

        % Button pushed function: XPButton
        function XPButtonPushed(app, event)
            app.teachUR3([1; 0; 0; 0; 0; 0]);
        end

        % Button pushed function: XMButton
        function XMButtonPushed(app, event)
            app.teachUR3([-1; 0; 0; 0; 0; 0]);
        end

        % Button pushed function: YPButton
        function YPButtonPushed(app, event)
            app.teachUR3([0; 1; 0; 0; 0; 0]);
        end

        % Button pushed function: YMButton
        function YMButtonPushed(app, event)
            app.teachUR3([0;-1; 0; 0; 0; 0]);
        end

        % Button pushed function: ZPButton
        function ZPButtonPushed(app, event)
            app.teachUR3([0; 0; 1; 0; 0; 0]);
        end

        % Button pushed function: ZMButton
        function ZMButtonPushed(app, event)
            app.teachUR3([0; 0;-1; 0; 0; 0]);
        end

        % Button pushed function: RPButton
        function RPButtonPushed(app, event)
            app.teachUR3([0; 0; 0; 10; 0; 0]);
        end

        % Button pushed function: RMButton
        function RMButtonPushed(app, event)
            app.teachUR3([0; 0; 0;-10; 0; 0]);
        end

        % Button pushed function: PPButton
        function PPButtonPushed(app, event)
            app.teachUR3([0; 0; 0; 0; 10; 0]);
        end

        % Button pushed function: PMButton
        function PMButtonPushed(app, event)
            app.teachUR3([0; 0; 0; 0;-10; 0]);
        end

        % Button pushed function: YawPButton
        function YawPButtonPushed(app, event)
            app.teachUR3([0; 0; 0; 0; 0; 10]);
        end

        % Button pushed function: YawMButton
        function YawMButtonPushed(app, event)
            app.teachUR3([0; 0; 0; 0; 0;-10]);
        end

        % Value changing function: Joint1Spinner2
        function Joint1Spinner2ValueChanging(app, event)
            app.Joint1Spinner2.Value = event.Value;
            app.animateMeca500;
        end

        % Value changing function: Joint2Spinner2
        function Joint2Spinner2ValueChanging(app, event)
            app.Joint2Spinner2.Value = event.Value;
            app.animateMeca500;
        end

        % Value changing function: Joint3Spinner2
        function Joint3Spinner2ValueChanging(app, event)
            app.Joint3Spinner2.Value = event.Value;
            app.animateMeca500;
        end

        % Value changing function: Joint4Spinner2
        function Joint4Spinner2ValueChanging(app, event)
            app.Joint4Spinner2.Value = event.Value;
            app.animateMeca500;
        end

        % Value changing function: Joint5Spinner2
        function Joint5Spinner2ValueChanging(app, event)
            app.Joint5Spinner2.Value = event.Value;
            app.animateMeca500;
        end

        % Value changing function: Joint6Spinner2
        function Joint6Spinner2ValueChanging(app, event)
            app.Joint6Spinner2.Value = event.Value;
            app.animateMeca500;
        end

        % Button pushed function: XPButton2
        function XPButton2Pushed(app, event)
            app.teachMeca500([1; 0; 0; 0; 0; 0]);
        end

        % Button pushed function: XMButton2
        function XMButton2Pushed(app, event)
            app.teachMeca500([-1; 0; 0; 0; 0; 0]);
        end

        % Button pushed function: YPButton2
        function YPButton2Pushed(app, event)
            app.teachMeca500([0; 1; 0; 0; 0; 0]);
        end

        % Button pushed function: YMButton2
        function YMButton2Pushed(app, event)
            app.teachMeca500([0;-1; 0; 0; 0; 0]);
        end

        % Button pushed function: ZPButton2
        function ZPButton2Pushed(app, event)
            app.teachMeca500([0; 0; 1; 0; 0; 0]);
        end

        % Button pushed function: ZMButton2
        function ZMButton2Pushed(app, event)
            app.teachMeca500([0; 0;-1; 0; 0; 0]);
        end

        % Button pushed function: RPButton2
        function RPButton2Pushed(app, event)
            app.teachMeca500([0; 0; 0; 10; 0; 0]);
        end

        % Button pushed function: RMButton2
        function RMButton2Pushed(app, event)
            app.teachMeca500([0; 0; 0;-10; 0; 0]);
        end

        % Button pushed function: PPButton2
        function PPButton2Pushed(app, event)
            app.teachMeca500([0; 0; 0; 0; 10; 0]);
        end

        % Button pushed function: PMButton2
        function PMButton2Pushed(app, event)
            app.teachMeca500([0; 0; 0; 0;-10; 0]);
        end

        % Button pushed function: YawPButton2
        function YawPButton2Pushed(app, event)
            app.teachMeca500([0; 0; 0; 0; 0; 10]);
        end

        % Button pushed function: YawMButton2
        function YawMButton2Pushed(app, event)
            app.teachMeca500([0; 0; 0; 0; 0;-10]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SafeCoLogo = uiimage(app.UIFigure);
            app.SafeCoLogo.ImageSource = 'SafeCo Logo.png';
            app.Subtitle = uilabel(app.UIFigure);
            app.Subtitle.Text = 'See the future clearer';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'SwipeBot';
            app.UR3Panel = uipanel(app.UIFigure);
            app.UR3Panel.Title = 'UR3';
            app.Arrows = uiimage(app.UR3Panel);
            app.Arrows.ImageSource = 'Arrow1.png';
            app.Joint1SpinnerLabel = uilabel(app.UR3Panel);
            app.Joint1SpinnerLabel.Text = 'Joint 1';
            app.Joint1Spinner = uispinner(app.UR3Panel);
            app.Joint1Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint1SpinnerValueChanging, true);
            app.Joint1Spinner.Limits = [-360 360];
            app.Joint1Spinner.ValueDisplayFormat = '%.4f';
            app.Joint2SpinnerLabel = uilabel(app.UR3Panel);
            app.Joint2SpinnerLabel.Text = 'Joint 2';
            app.Joint2Spinner = uispinner(app.UR3Panel);
            app.Joint2Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint2SpinnerValueChanging, true);
            app.Joint2Spinner.Limits = [-360 360];
            app.Joint2Spinner.ValueDisplayFormat = '%.4f';
            app.Joint3SpinnerLabel = uilabel(app.UR3Panel);
            app.Joint3SpinnerLabel.Text = 'Joint 3';
            app.Joint3Spinner = uispinner(app.UR3Panel);
            app.Joint3Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint3SpinnerValueChanging, true);
            app.Joint3Spinner.Limits = [-360 360];
            app.Joint3Spinner.ValueDisplayFormat = '%.4f';
            app.Joint5SpinnerLabel = uilabel(app.UR3Panel);
            app.Joint5SpinnerLabel.Text = 'Joint 5';
            app.Joint5Spinner = uispinner(app.UR3Panel);
            app.Joint5Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint5SpinnerValueChanging, true);
            app.Joint5Spinner.Limits = [-360 360];
            app.Joint5Spinner.ValueDisplayFormat = '%.4f';
            app.Joint4SpinnerLabel = uilabel(app.UR3Panel);
            app.Joint4SpinnerLabel.Text = 'Joint 4';
            app.Joint4Spinner = uispinner(app.UR3Panel);
            app.Joint4Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint4SpinnerValueChanging, true);
            app.Joint4Spinner.Limits = [-360 360];
            app.Joint4Spinner.ValueDisplayFormat = '%.4f';
            app.Joint6SpinnerLabel = uilabel(app.UR3Panel);
            app.Joint6SpinnerLabel.Text = 'Joint 6';
            app.Joint6Spinner = uispinner(app.UR3Panel);
            app.Joint6Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint6SpinnerValueChanging, true);
            app.Joint6Spinner.Limits = [-360 360];
            app.Joint6Spinner.ValueDisplayFormat = '%.4f';
            app.XEditFieldLabel = uilabel(app.UR3Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UR3Panel, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.4f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UR3Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UR3Panel, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.4f';
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UR3Panel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UR3Panel, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.4f';
            app.ZEditField.Editable = 'off';
            app.REditFieldLabel = uilabel(app.UR3Panel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UR3Panel, 'numeric');
            app.REditField.ValueDisplayFormat = '%.4f';
            app.REditField.Editable = 'off';
            app.PEditFieldLabel = uilabel(app.UR3Panel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UR3Panel, 'numeric');
            app.PEditField.ValueDisplayFormat = '%.4f';
            app.PEditField.Editable = 'off';
            app.YEditField_2Label = uilabel(app.UR3Panel);
            app.YEditField_2Label.Text = 'Y';
            app.YawEditField = uieditfield(app.UR3Panel, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%.4f';
            app.YawEditField.Editable = 'off';
            app.ZPButton = uibutton(app.UR3Panel, 'push');
            app.ZPButton.ButtonPushedFcn = createCallbackFcn(app, @ZPButtonPushed, true);
            app.ZPButton.Text = 'Z+';
            app.ZMButton = uibutton(app.UR3Panel, 'push');
            app.ZMButton.ButtonPushedFcn = createCallbackFcn(app, @ZMButtonPushed, true);
            app.ZMButton.Text = 'Z-';
            app.YPButton = uibutton(app.UR3Panel, 'push');
            app.YPButton.ButtonPushedFcn = createCallbackFcn(app, @YPButtonPushed, true);
            app.YPButton.Text = 'Y+';
            app.YMButton = uibutton(app.UR3Panel, 'push');
            app.YMButton.ButtonPushedFcn = createCallbackFcn(app, @YMButtonPushed, true);
            app.YMButton.Text = 'Y-';
            app.XPButton = uibutton(app.UR3Panel, 'push');
            app.XPButton.ButtonPushedFcn = createCallbackFcn(app, @XPButtonPushed, true);
            app.XPButton.Text = 'X+';
            app.XMButton = uibutton(app.UR3Panel, 'push');
            app.XMButton.ButtonPushedFcn = createCallbackFcn(app, @XMButtonPushed, true);
            app.XMButton.Text = 'X-';
            app.Turns = uiimage(app.UR3Panel);
            app.Turns.ImageSource = 'Turns.png';
            app.RMButton = uibutton(app.UR3Panel, 'push');
            app.RMButton.ButtonPushedFcn = createCallbackFcn(app, @RMButtonPushed, true);
            app.RMButton.Text = 'R-';
            app.RPButton = uibutton(app.UR3Panel, 'push');
            app.RPButton.ButtonPushedFcn = createCallbackFcn(app, @RPButtonPushed, true);
            app.RPButton.Text = 'R+';
            app.PMButton = uibutton(app.UR3Panel, 'push');
            app.PMButton.ButtonPushedFcn = createCallbackFcn(app, @PMButtonPushed, true);
            app.PMButton.Text = 'P-';
            app.PPButton = uibutton(app.UR3Panel, 'push');
            app.PPButton.ButtonPushedFcn = createCallbackFcn(app, @PPButtonPushed, true);
            app.PPButton.Text = 'P+';
            app.YawPButton = uibutton(app.UR3Panel, 'push');
            app.YawPButton.ButtonPushedFcn = createCallbackFcn(app, @YawPButtonPushed, true);
            app.YawPButton.Text = 'Y+';
            app.YawMButton = uibutton(app.UR3Panel, 'push');
            app.YawMButton.ButtonPushedFcn = createCallbackFcn(app, @YawMButtonPushed, true);
            app.YawMButton.Text = 'Y-';
            app.Meca500Panel = uipanel(app.UIFigure);
            app.Meca500Panel.Title = 'Meca 500';
            app.Arrows2 = uiimage(app.Meca500Panel);
            app.Arrows2.ImageSource = 'Arrow1.png';
            app.ZPButton2 = uibutton(app.Meca500Panel, 'push');
            app.ZPButton2.ButtonPushedFcn = createCallbackFcn(app, @ZPButton2Pushed, true);
            app.ZPButton2.Text = 'Z+';
            app.ZMButton2 = uibutton(app.Meca500Panel, 'push');
            app.ZMButton2.ButtonPushedFcn = createCallbackFcn(app, @ZMButton2Pushed, true);
            app.ZMButton2.Text = 'Z-';
            app.YPButton2 = uibutton(app.Meca500Panel, 'push');
            app.YPButton2.ButtonPushedFcn = createCallbackFcn(app, @YPButton2Pushed, true);
            app.YPButton2.Text = 'Y+';
            app.YMButton2 = uibutton(app.Meca500Panel, 'push');
            app.YMButton2.ButtonPushedFcn = createCallbackFcn(app, @YMButton2Pushed, true);
            app.YMButton2.Text = 'Y-';
            app.XPButton2 = uibutton(app.Meca500Panel, 'push');
            app.XPButton2.ButtonPushedFcn = createCallbackFcn(app, @XPButton2Pushed, true);
            app.XPButton2.Text = 'X+';
            app.XMButton2 = uibutton(app.Meca500Panel, 'push');
            app.XMButton2.ButtonPushedFcn = createCallbackFcn(app, @XMButton2Pushed, true);
            app.XMButton2.Text = 'X-';
            app.Turns2 = uiimage(app.Meca500Panel);
            app.Turns2.ImageSource = 'Turns.png';
            app.RMButton2 = uibutton(app.Meca500Panel, 'push');
            app.RMButton2.ButtonPushedFcn = createCallbackFcn(app, @RMButton2Pushed, true);
            app.RMButton2.Text = 'R-';
            app.RPButton2 = uibutton(app.Meca500Panel, 'push');
            app.RPButton2.ButtonPushedFcn = createCallbackFcn(app, @RPButton2Pushed, true);
            app.RPButton2.Text = 'R+';
            app.PMButton2 = uibutton(app.Meca500Panel, 'push');
            app.PMButton2.ButtonPushedFcn = createCallbackFcn(app, @PMButton2Pushed, true);
            app.PMButton2.Text = 'P-';
            app.PPButton2 = uibutton(app.Meca500Panel, 'push');
            app.PPButton2.ButtonPushedFcn = createCallbackFcn(app, @PPButton2Pushed, true);
            app.PPButton2.Text = 'P+';
            app.YawPButton2 = uibutton(app.Meca500Panel, 'push');
            app.YawPButton2.ButtonPushedFcn = createCallbackFcn(app, @YawPButton2Pushed, true);
            app.YawPButton2.Text = 'Y+';
            app.YawMButton2 = uibutton(app.Meca500Panel, 'push');
            app.YawMButton2.ButtonPushedFcn = createCallbackFcn(app, @YawMButton2Pushed, true);
            app.YawMButton2.Text = 'Y-';
            app.Joint1Spinner_2Label = uilabel(app.Meca500Panel);
            app.Joint1Spinner_2Label.Text = 'Joint 1';
            app.Joint1Spinner2 = uispinner(app.Meca500Panel);
            app.Joint1Spinner2.ValueChangingFcn = createCallbackFcn(app, @Joint1Spinner2ValueChanging, true);
            app.Joint1Spinner2.Limits = [-175 175];
            app.Joint1Spinner2.ValueDisplayFormat = '%.4f';
            app.Joint2Spinner_2Label = uilabel(app.Meca500Panel);
            app.Joint2Spinner_2Label.Text = 'Joint 2';
            app.Joint2Spinner2 = uispinner(app.Meca500Panel);
            app.Joint2Spinner2.ValueChangingFcn = createCallbackFcn(app, @Joint2Spinner2ValueChanging, true);
            app.Joint2Spinner2.Limits = [-90 70];
            app.Joint2Spinner2.ValueDisplayFormat = '%.4f';
            app.Joint3Spinner_2Label = uilabel(app.Meca500Panel);
            app.Joint3Spinner_2Label.Text = 'Joint 3';
            app.Joint3Spinner2 = uispinner(app.Meca500Panel);
            app.Joint3Spinner2.ValueChangingFcn = createCallbackFcn(app, @Joint3Spinner2ValueChanging, true);
            app.Joint3Spinner2.Limits = [-70 135];
            app.Joint3Spinner2.ValueDisplayFormat = '%.4f';
            app.Joint5Spinner_2Label = uilabel(app.Meca500Panel);
            app.Joint5Spinner_2Label.Text = 'Joint 5';
            app.Joint5Spinner2 = uispinner(app.Meca500Panel);
            app.Joint5Spinner2.ValueChangingFcn = createCallbackFcn(app, @Joint5Spinner2ValueChanging, true);
            app.Joint5Spinner2.Limits = [-115 115];
            app.Joint5Spinner2.ValueDisplayFormat = '%.4f';
            app.Joint4Spinner_2Label = uilabel(app.Meca500Panel);
            app.Joint4Spinner_2Label.Text = 'Joint 4';
            app.Joint4Spinner2 = uispinner(app.Meca500Panel);
            app.Joint4Spinner2.ValueChangingFcn = createCallbackFcn(app, @Joint4Spinner2ValueChanging, true);
            app.Joint4Spinner2.Limits = [-170 170];
            app.Joint4Spinner2.ValueDisplayFormat = '%.4f';
            app.Joint6Spinner_2Label = uilabel(app.Meca500Panel);
            app.Joint6Spinner_2Label.Text = 'Joint 6';
            app.Joint6Spinner2 = uispinner(app.Meca500Panel);
            app.Joint6Spinner2.ValueChangingFcn = createCallbackFcn(app, @Joint6Spinner2ValueChanging, true);
            app.Joint6Spinner2.ValueDisplayFormat = '%.4f';
            app.XEditField_2Label = uilabel(app.Meca500Panel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField2 = uieditfield(app.Meca500Panel, 'numeric');
            app.XEditField2.ValueDisplayFormat = '%.4f';
            app.XEditField2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.Meca500Panel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField2 = uieditfield(app.Meca500Panel, 'numeric');
            app.ZEditField2.ValueDisplayFormat = '%.4f';
            app.ZEditField2.Editable = 'off';
            app.REditField_2Label = uilabel(app.Meca500Panel);
            app.REditField_2Label.Text = 'R';
            app.REditField2 = uieditfield(app.Meca500Panel, 'numeric');
            app.REditField2.ValueDisplayFormat = '%.4f';
            app.REditField2.Editable = 'off';
            app.PEditField_2Label = uilabel(app.Meca500Panel);
            app.PEditField_2Label.Text = 'P';
            app.PEditField2 = uieditfield(app.Meca500Panel, 'numeric');
            app.PEditField2.ValueDisplayFormat = '%.4f';
            app.PEditField2.Editable = 'off';
            app.YEditField_3Label = uilabel(app.Meca500Panel);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField2 = uieditfield(app.Meca500Panel, 'numeric');
            app.YEditField2.ValueDisplayFormat = '%.4f';
            app.YEditField2.Editable = 'off';
            app.YEditField_4Label = uilabel(app.Meca500Panel);
            app.YEditField_4Label.Text = 'Y';
            app.YawEditField2 = uieditfield(app.Meca500Panel, 'numeric');
            app.YawEditField2.ValueDisplayFormat = '%.4f';
            app.YawEditField2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwipeBotTeach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end